classdef AeolisSandFenceInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        DrawGridExtentButton           matlab.ui.control.Button
        GriddxmEditFieldLabel          matlab.ui.control.Label
        GriddxmEditField               matlab.ui.control.NumericEditField
        AddFenceLocationsButton        matlab.ui.control.Button
        ScenarioAttributesLabel        matlab.ui.control.Label
        DurationdaysEditFieldLabel     matlab.ui.control.Label
        DurationdaysEditField          matlab.ui.control.NumericEditField
        MaxWindSpeedmsEditFieldLabel   matlab.ui.control.Label
        MaxWindSpeedmsEditField        matlab.ui.control.NumericEditField
        MeanWindDirectionoLabel        matlab.ui.control.Label
        MeanWindDirectionoEditField    matlab.ui.control.NumericEditField
        WindDirectionSpreadoEditFieldLabel  matlab.ui.control.Label
        WindDirectionSpreadoEditField  matlab.ui.control.NumericEditField
        MinWindSpeedmsEditFieldLabel   matlab.ui.control.Label
        MinWindSpeedmsEditField        matlab.ui.control.NumericEditField
        TimeStepsecsEditFieldLabel     matlab.ui.control.Label
        TimeStepsecsEditField          matlab.ui.control.NumericEditField
        ModelSetupLabel                matlab.ui.control.Label
        RunModelLabel                  matlab.ui.control.Label
        RunModelButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        gridobj  % object to create grid 
        fenceobj % object to create fences
        scenario % store all data that goes to Aeolis
    end

    methods (Access = private)
    
        function results = func(app)
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Add Map to Plot
            file = '/home/nick/Documents/scripts/AeolisInterface/bensonbeach_map_close.tif';
            A = imread(file);
            A = squeeze(A(:,:,1:3));            
            imshow(A,'Parent',app.UIAxes);
          
        end

        % Button pushed function: DrawGridExtentButton
        function DrawGridExtentButtonPushed(app, event)
            app.gridobj = drawrectangle(app.UIAxes);
        end

        % Button pushed function: AddFenceLocationsButton
        function AddFenceLocationsButtonPushed(app, event)

            %Draw Line for fence
            lineobj = drawline(app.UIAxes);

            %Determine Fence Number
            try 
                app.fenceobj.fencenums;
                app.fenceobj.fencenums = app.fenceobj.fencenums+1;
            catch err
                app.fenceobj.fencenums = 1;    
            end
            
            app.DialogApp = FenceHeightDialogBox(app);

            %Save Fence Data
            eval(['app.fenceobj.lineobj', num2str(app.fenceobj.fencenums),' = lineobj;'])
            eval(['app.fenceobj.fenceheight', num2str(app.fenceobj.fencenums),' = app.DialogApp.FenceHeightAboveBedmEditField.Value;'])
            
        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            
            %Generate Wind Data  
            
                %Temporarily Store Input Values
                dt = app.TimeStepsecsEditField.Value;
                day_end = app.DurationdaysEditField.Value;
                max_ws = app.MaxWindSpeedmsEditField.Value;
                min_ws = app.MinWindSpeedmsEditField.Value;
                mean_wd = app.MeanWindDirectionoEditField.Value;
                spread_wd = app.WindDirectionSpreadoEditField.Value;            
                
                %Generate Time Series
                times = 0:dt:(day_end*24*60*60);
                ws = [(max_ws-min_ws)*rand(size(times))]+min_ws;
                dirs = wrapTo180([[spread_wd*rand(size(times))]-spread_wd/2]+mean_wd);
               
                %Store Wind Time Series             
                matrix = [times(:) ws(:) dirs(:)];
                app.scenario.winds.windmatrix = matrix;
                dlmwrite('aeolis_winds.txt', matrix, ' ');

            %Generate Grid
            
                %Create Grid
                dummyz = 0; %temporary fixed value for elevation
                minx = app.gridobj.Position(1);
                maxx = app.gridobj.Position(3);
                miny = app.gridobj.Position(2);
                maxy = app.gridobj.Position(14);
                dx = app.GriddxmEditField.Value;
                xs = floor(minx):dx:ceil(maxx);
                ys = floor(miny):dx:ceil(maxy);
                [YS, XS] = meshgrid(ys, xs);
                ZS = ones(size(XS))*dummyz;
                
                %Store Elevation/Model Grid
                app.scenario.grids.x = xs;
                app.scenario.grids.y = ys;
                app.scenario.grids.XS = XS;
                app.scenario.grids.YS = YS;
                app.scenario.grids.ZS = ZS;
                dlmwrite('x.grd', XS, ' ');
                dlmwrite('y.grd', YS, ' ');
                dlmwrite('z.grd', ZS, ' ');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.DrawGridExtentButton = uibutton(app.UIFigure, 'push');
            app.DrawGridExtentButton.ButtonPushedFcn = createCallbackFcn(app, @DrawGridExtentButtonPushed, true);
            app.DrawGridExtentButton.Text = 'Draw Grid Extent';
            app.GriddxmEditFieldLabel = uilabel(app.UIFigure);
            app.GriddxmEditFieldLabel.Text = 'Grid dx (m)';
            app.GriddxmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GriddxmEditField.Limits = [0.1 100];
            app.GriddxmEditField.Value = 1;
            app.AddFenceLocationsButton = uibutton(app.UIFigure, 'push');
            app.AddFenceLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @AddFenceLocationsButtonPushed, true);
            app.AddFenceLocationsButton.Text = 'Add Fence Locations';
            app.ScenarioAttributesLabel = uilabel(app.UIFigure);
            app.ScenarioAttributesLabel.Text = 'Scenario Attributes';
            app.DurationdaysEditFieldLabel = uilabel(app.UIFigure);
            app.DurationdaysEditFieldLabel.Text = 'Duration (days)';
            app.DurationdaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationdaysEditField.Limits = [0.01 Inf];
            app.DurationdaysEditField.Value = 100;
            app.MaxWindSpeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxWindSpeedmsEditFieldLabel.Text = 'Max Wind Speed (m/s)';
            app.MaxWindSpeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxWindSpeedmsEditField.Limits = [0 100];
            app.MaxWindSpeedmsEditField.Value = 20;
            app.MeanWindDirectionoLabel = uilabel(app.UIFigure);
            app.MeanWindDirectionoLabel.Text = 'Mean Wind Direction (o)';
            app.MeanWindDirectionoEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindDirectionSpreadoEditFieldLabel = uilabel(app.UIFigure);
            app.WindDirectionSpreadoEditFieldLabel.Text = 'Wind Direction Spread (o)';
            app.WindDirectionSpreadoEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindDirectionSpreadoEditField.Limits = [0 180];
            app.WindDirectionSpreadoEditField.Value = 20;
            app.MinWindSpeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.MinWindSpeedmsEditFieldLabel.Text = 'Min Wind Speed (m/s)';
            app.MinWindSpeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinWindSpeedmsEditField.Limits = [0 100];
            app.MinWindSpeedmsEditField.Value = 4;
            app.TimeStepsecsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepsecsEditFieldLabel.Text = 'Time Step (secs)';
            app.TimeStepsecsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepsecsEditField.Limits = [0.1 86400];
            app.TimeStepsecsEditField.Value = 3600;
            app.ModelSetupLabel = uilabel(app.UIFigure);
            app.ModelSetupLabel.Text = 'Model Setup';
            app.RunModelLabel = uilabel(app.UIFigure);
            app.RunModelLabel.Text = 'Run Model';
            app.RunModelButton = uibutton(app.UIFigure, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AeolisSandFenceInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end